classdef exp_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CheckconnectionsButton   matlab.ui.control.Button
        TimeliteLamp             matlab.ui.control.Lamp
        TimeliteLampLabel        matlab.ui.control.Label
        CamLamp                  matlab.ui.control.Lamp
        CamLampLabel             matlab.ui.control.Label
        StimLamp                 matlab.ui.control.Lamp
        StimLampLabel            matlab.ui.control.Label
        StartButton              matlab.ui.control.StateButton
        ChooseprotocolButton     matlab.ui.control.StateButton
        MousenameEditField       matlab.ui.control.EditField
        MousenameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        protocol % Description
        mouse_name
        date_today = string(datetime('today', 'Format', 'yyyy_MM_dd'));
        server_stim = tcpserver("0.0.0.0",plab.locations.bonsai_port);
        server_timelite = tcpserver("0.0.0.0",plab.locations.timelite_port);
        server_mousecam = tcpserver("0.0.0.0",plab.locations.mousecam_port);
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseprotocolButton
        function ChooseprotocolButtonValueChanged(app, event)
            app.protocol = uigetfile('C:\Users\peterslab\Documents\GitHub\PetersLab_code\Bonsai stuff\*.bonsai');
            figure(app.UIFigure);
            app.ChooseprotocolButton.Text = ['Chosen ' app.protocol];
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1
                s = struct('mouse', app.mouse_name, 'date', app.date_today, 'protocol', app.protocol);
                encode_s = jsonencode(s);
                % write(server_stim, encode_s,'string');
                write(app.server_stim,encode_s,'string');
                app.StartButton.Text = 'Stop';
            else
                write(app.server_stim,'stop','string');
%                 write(app.server_timelite, 'stop', 'string');
%                 write(app.server_mousecam, 'stop', 'string');
                app.StartButton.Text = 'Start';
            end

        end

        % Value changed function: MousenameEditField
        function MousenameEditFieldValueChanged(app, event)
            app.mouse_name =  app.MousenameEditField.Value;
        end

        % Button pushed function: CheckconnectionsButton
        function CheckconnectionsButtonPushed(app, event)
            if app.server_stim.Connected==1
                app.StimLamp.Color = [0.00,1.00,0.00];
            else
                app.StimLamp.Color = [1.00, 0.00, 0.00];
            end

            if app.server_mousecam.Connected==1
                app.CamLamp.Color = [0.00,1.00,0.00];
            else
                app.CamLamp.Color = [1.00, 0.00, 0.00];
            end

            if app.server_timelite.Connected==1
                app.TimeliteLamp.Color = [0.00,1.00,0.00];
            else
                app.TimeliteLamp.Color = [1.00, 0.00, 0.00];
            end
%             waitfor(app.server_mousecam, 'Connected', 1);
%             app.CamLamp.Enable = 'on';
%             waitfor(app.server_timelite, 'Connected', 1);
%             app.TimeliteLamp.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MousenameEditFieldLabel = uilabel(app.UIFigure);
            app.MousenameEditFieldLabel.Text = 'Mouse name';
            app.MousenameEditField = uieditfield(app.UIFigure, 'text');
            app.MousenameEditField.ValueChangedFcn = createCallbackFcn(app, @MousenameEditFieldValueChanged, true);
            app.ChooseprotocolButton = uibutton(app.UIFigure, 'state');
            app.ChooseprotocolButton.ValueChangedFcn = createCallbackFcn(app, @ChooseprotocolButtonValueChanged, true);
            app.ChooseprotocolButton.Text = 'Choose protocol';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StimLampLabel = uilabel(app.UIFigure);
            app.StimLampLabel.Text = 'Stim';
            app.StimLamp = uilamp(app.UIFigure);
            app.CamLampLabel = uilabel(app.UIFigure);
            app.CamLampLabel.Text = 'Cam';
            app.CamLamp = uilamp(app.UIFigure);
            app.TimeliteLampLabel = uilabel(app.UIFigure);
            app.TimeliteLampLabel.Text = 'Timelite';
            app.TimeliteLamp = uilamp(app.UIFigure);
            app.CheckconnectionsButton = uibutton(app.UIFigure, 'push');
            app.CheckconnectionsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckconnectionsButtonPushed, true);
            app.CheckconnectionsButton.Text = 'Check connections';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end